classdef SentryDroneApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KILLSWITCHButton        matlab.ui.control.Button
        StatusRozczonyLabel     matlab.ui.control.Label
        BATTERYGauge            matlab.ui.control.LinearGauge
        BATTERYGaugeLabel       matlab.ui.control.Label
        STARTTRACKINGButton     matlab.ui.control.Button
        LANDButton              matlab.ui.control.Button
        TAKEOFFButton           matlab.ui.control.Button
        CONNECTWITHTELLOButton  matlab.ui.control.Button
        UIAxesVideo             matlab.ui.control.UIAxes
    end

    properties (Access = private)
        droneObj            % Obiekt drona ryze()
        camObj              % Aktywny uchwyt kamery (Tello lub webcam)
        udpClient           % Szybkie gniazdo UDP do komend awaryjnych
        imgHandle           % Uchwyt do CData (szybkie odświeżanie)
        faceDetector        % vision.CascadeObjectDetector
        isTracking = false  % Flaga pętli śledzenia
        isSimulation = true % Flaga trybu pracy

        % Pamięć stanów regulatora PD
        prev_err_yaw = 0;
        prev_err_z   = 0;
        filt_d_yaw   = 0;
        filt_d_z     = 0;
        frameCount   = 0;
     end
    
     methods (Access = private)
         function val = deadbandHelper(~, in, th)
             if abs(in) <= th
                 val = 0;
             else
                 val = in - sign(in) * th;
             end
         end

         function sendUDP(app, cmdStr)
             if ~app.isSimulation && ~isempty(app.udpClient)
                 try
                     write(app.udpClient, uint8(cmdStr), "192.168.10.1", 8889);
                 catch
                 end
             end
         end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTWITHTELLOButton
        function CONNECTWITHTELLOButtonPushed(app, event)

            app.StatusRozczonyLabel.Text = 'Status: Łączenie z Tello...';
            drawnow;

            % 1. Konfiguracja bezpiecznego kanału UDP
            try
                if isempty(app.udpClient)
                    app.udpClient = udpport("IPV4");
                end
                % Autoryzacja SDK Tello na porcie UDP
                write(app.udpClient, uint8('command'), "192.168.10.1", 8889);
            catch
            end

            % 2. Próba połączenia przez oficjalny pakiet
            try
                app.droneObj = ryze();
                app.camObj = camera(app.droneObj);
                app.isSimulation = false;

                bat = app.droneObj.BatteryLevel;
                app.BATTERYGauge.Value = bat;
                app.StatusRozczonyLabel.Text = sprintf('Status: Połączono! Bateria: %d%% (Gotowy)', bat);
            catch
                % Fallback symulacyjny na wypadek braku drona
                try
                    app.camObj = webcam(1);
                    app.isSimulation = true;
                    app.BATTERYGauge.Value = 100;
                    app.StatusRozczonyLabel.Text = 'Status: Tryb PC (Kamera laptopa)';
                catch ME
                    app.StatusRozczonyLabel.Text = ['Błąd kamery: ' ME.message];
                    return;
                end
            end

            % 3. Detektor Viola-Jones
            app.faceDetector = vision.CascadeObjectDetector('FrontalFaceCART');
            app.faceDetector.MinSize = [40 40];
            app.faceDetector.MergeThreshold = 5;
        end

        % Button pushed function: TAKEOFFButton
        function TAKEOFFButtonPushed(app, event)
            if ~app.isSimulation && ~isempty(app.droneObj)
                app.StatusRozczonyLabel.Text = 'Status: Start (Takeoff)... Nie dotykaj drona!';
                drawnow;

                % Czysty start bez wysyłania jakichkolwiek śmiecących komend prędkości
                takeoff(app.droneObj);

                app.StatusRozczonyLabel.Text = 'Status: Stabilny zawis (Autopilot VPS)';
            else
                app.StatusRozczonyLabel.Text = 'Status: [SYMULACJA] Start wirtualny OK';
            end
        end

        % Button pushed function: LANDButton
        function LANDButtonPushed(app, event)
            % 1. Natychmiastowe ubicie flagi pętli
            app.isTracking = false;
            app.StatusRozczonyLabel.Text = 'Status: LĄDOWANIE NATYCHMIASTOWE...';
            drawnow;

            % 2. Zatrzymanie ruchu i rozkaz lądowania
            if ~app.isSimulation
                app.sendUDP('rc 0 0 0 0');
                app.sendUDP('land');
                if ~isempty(app.droneObj)
                    try
                        land(app.droneObj);
                    catch
                    end
                end
                app.StatusRozczonyLabel.Text = 'Status: Wylądowano bezpiecznie';
            else
                app.StatusRozczonyLabel.Text = 'Status: Zatrzymano śledzenie';
            end
        end

        % Button pushed function: STARTTRACKINGButton
        function STARTTRACKINGButtonPushed(app, event)
           if isempty(app.camObj) 
                app.StatusRozczonyLabel.Text = 'Błąd: Najpierw kliknij CONNECT!';
                return;
            end
            if app.isTracking
                return;
            end
            
            app.isTracking = true;
            app.frameCount = 0;
            app.prev_err_yaw = 0; app.filt_d_yaw = 0;
            app.prev_err_z   = 0; app.filt_d_z   = 0;
            app.StatusRozczonyLabel.Text = 'Status: Śledzenie twarzy AKTYWNE';

            % Odczyt klatki startowej
            testFrame = snapshot(app.camObj);
            [imgH, imgW, ~] = size(testFrame);
            centerRef = [imgW / 2, imgH / 2];

            % Szybki uchwyt do obrazu
            app.imgHandle = imshow(testFrame, 'Parent', app.UIAxesVideo);
            axis(app.UIAxesVideo, 'image', 'off');

            tPrev = tic;
            tLastCmd = tic;
            scaleFactor = 0.5; % Przyspieszenie 4x analizy obrazu

            while app.isTracking
                dt = toc(tPrev);
                tPrev = tic;
                if dt <= 0 || dt > 0.4, dt = 0.05; end

                % 1. Pobranie klatki
                frame = snapshot(app.camObj);
                if ~app.isTracking, break; end % Błyskawiczny bezpiecznik po kliknięciu LAND

                if isempty(frame)
                    drawnow;
                    continue;
                end

                app.frameCount = app.frameCount + 1;
                if app.isSimulation
                    frame = fliplr(frame);
                end

                % 2. Szybka detekcja twarzy
                smallFrame = imresize(frame, scaleFactor);
                bboxesSmall = app.faceDetector(smallFrame);
                bboxes = bboxesSmall / scaleFactor;

                if ~isempty(bboxes) && app.isTracking
                    % Największa twarz
                    areas = bboxes(:, 3) .* bboxes(:, 4);
                    [~, maxIdx] = max(areas);
                    activeBox = bboxes(maxIdx, :);
                    faceCenter = [activeBox(1) + activeBox(3)/2, activeBox(2) + activeBox(4)/2];

                    % Uchyby znormalizowane [-1.0, 1.0]
                    raw_err_yaw = (faceCenter(1) - centerRef(1)) / centerRef(1);
                    raw_err_z   = (centerRef(2) - faceCenter(2)) / centerRef(2);

                    % Bezpieczna martwa strefa (10% środka kadru nie wywołuje ruchu)
                    err_yaw = app.deadbandHelper(raw_err_yaw, 0.10);
                    err_z   = app.deadbandHelper(raw_err_z, 0.10);

                    % Regulator PD z filtrem dolnoprzepustowym na obrót
                    d_yaw = (err_yaw - app.prev_err_yaw) / dt;
                    app.filt_d_yaw = 0.70 * app.filt_d_yaw + 0.30 * d_yaw;
                    cmd_yaw = 0.35 * err_yaw + 0.05 * app.filt_d_yaw;

                    % Regulator PD na wysokość
                    d_z = (err_z - app.prev_err_z) / dt;
                    app.filt_d_z = 0.70 * app.filt_d_z + 0.30 * d_z;
                    cmd_z = 0.35 * err_z + 0.04 * app.filt_d_z;

                    app.prev_err_yaw = err_yaw;
                    app.prev_err_z   = err_z;

                    % Obcięcie prędkości do bezpiecznych wartości (-25 do 25%)
                    yaw_rc = round(max(min(cmd_yaw * 100, 25), -25));
                    z_rc   = round(max(min(cmd_z * 100, 20), -20));

                    % Grafika na obrazie
                    frame = insertShape(frame, 'Rectangle', activeBox, 'LineWidth', 2, 'Color', 'cyan');
                    frame = insertShape(frame, 'Line', [centerRef(1), centerRef(2), faceCenter(1), faceCenter(2)], ...
                        'LineWidth', 2, 'Color', 'magenta');

                    % Wysyłanie komend do Tello (co 100 ms)
                    if ~app.isSimulation && toc(tLastCmd) >= 0.10 && app.isTracking
                        app.sendUDP(sprintf('rc 0 0 %d %d', z_rc, yaw_rc));
                        tLastCmd = tic;
                    end
                else
                    % Brak twarzy w kadrze -> TWARDY ZAWIS W MIEJSCU (nie skręcaj!)
                    if ~app.isSimulation && toc(tLastCmd) >= 0.15 && app.isTracking
                        app.sendUDP('rc 0 0 0 0');
                        tLastCmd = tic;
                    end
                end

                % Celownik środka kadru
                cx = round(centerRef(1)); cy = round(centerRef(2));
                frame = insertShape(frame, 'Line', [cx-15, cy, cx+15, cy; cx, cy-15, cx, cy+15], ...
                    'LineWidth', 1, 'Color', 'yellow');

                % Błyskawiczna podmiana klatki w GUI
                if isvalid(app.imgHandle)
                    app.imgHandle.CData = frame;
                end

                % Sprawdzanie baterii rzadko (co 50 klatek), aby nie obciążać Wi-Fi
                if ~app.isSimulation && ~isempty(app.droneObj) && mod(app.frameCount, 50) == 0
                    try
                        app.BATTERYGauge.Value = app.droneObj.BatteryLevel;
                    catch
                    end
                end

                % Kluczowe: natychmiastowa obsługa kliknięć myszy
                drawnow;
            end

            % Zawsze po wyjściu z pętli zatrzymaj ruch drona
            if ~app.isSimulation
                app.sendUDP('rc 0 0 0 0');
            end
        end

        % Button pushed function: KILLSWITCHButton
        function ButtonPushedFcn(app, event)
            % NATYCHMIASTOWE ODCIĘCIE ZASILANIA (0 ms)
            app.isTracking = false;
            app.StatusRozczonyLabel.Text = '!!! KILL SWITCH: SILNIKI ODCIĘTE !!!';
            drawnow;

            % 1. Prawdziwa twarda komenda sprzętowa MATLAB-a
            if ~app.isSimulation && ~isempty(app.droneObj)
                try
                    abort(app.droneObj);
                catch
                end
            end

            % 2. Równoległy twardy pakiet emergency po UDP
            app.sendUDP('emergency');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesVideo = uiaxes(app.UIFigure);
            zlabel(app.UIAxesVideo, 'Z')
            app.CONNECTWITHTELLOButton = uibutton(app.UIFigure, 'push');
            app.CONNECTWITHTELLOButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTWITHTELLOButtonPushed, true);
            app.CONNECTWITHTELLOButton.Text = 'CONNECT WITH TELLO';
            app.TAKEOFFButton = uibutton(app.UIFigure, 'push');
            app.TAKEOFFButton.ButtonPushedFcn = createCallbackFcn(app, @TAKEOFFButtonPushed, true);
            app.TAKEOFFButton.Text = 'TAKEOFF';
            app.LANDButton = uibutton(app.UIFigure, 'push');
            app.LANDButton.ButtonPushedFcn = createCallbackFcn(app, @LANDButtonPushed, true);
            app.LANDButton.Text = 'LAND';
            app.STARTTRACKINGButton = uibutton(app.UIFigure, 'push');
            app.STARTTRACKINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTRACKINGButtonPushed, true);
            app.STARTTRACKINGButton.Text = 'START TRACKING';
            app.BATTERYGaugeLabel = uilabel(app.UIFigure);
            app.BATTERYGaugeLabel.Text = 'BATTERY [%]';
            app.BATTERYGauge = uigauge(app.UIFigure, 'linear');
            app.StatusRozczonyLabel = uilabel(app.UIFigure);
            app.StatusRozczonyLabel.Text = 'Status: Rozłączony';
            app.KILLSWITCHButton = uibutton(app.UIFigure, 'push');
            app.KILLSWITCHButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.KILLSWITCHButton.Text = 'KILLSWITCH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SentryDroneApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end